classdef FIR_FilterDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FIRFilterDesignerUIFigure     matlab.ui.Figure
        MagnitudeResponsePanel        matlab.ui.container.Panel
        figure                        matlab.ui.control.UIAxes
        LogCheckBox                   matlab.ui.control.CheckBox
        FilterLengthPanel             matlab.ui.container.Panel
        NooftapsSpinnerLabel          matlab.ui.control.Label
        NooftapsSpinner               matlab.ui.control.Spinner
        OptionsPanel                  matlab.ui.container.Panel
        WindowDropDownLabel           matlab.ui.control.Label
        WindowDropDown                matlab.ui.control.DropDown
        BetaEditFieldLabel            matlab.ui.control.Label
        parEditField                  matlab.ui.control.NumericEditField
        noOptionsPanel                matlab.ui.container.Panel
        TherearenooptionsforthisdesignmethodLabel  matlab.ui.control.Label
        DesignButton                  matlab.ui.control.Button
        MagnitudeSpecificationsPanel  matlab.ui.container.Panel
        EnteraweightvalueforeachbanbelowLabel  matlab.ui.control.Label
        WpassEditFieldLabel           matlab.ui.control.Label
        WpassEditField                matlab.ui.control.NumericEditField
        WstopEditFieldLabel           matlab.ui.control.Label
        WstopEditField                matlab.ui.control.NumericEditField
        boOptionsPanel                matlab.ui.container.Panel
        AttenuationisfixedathalfthepassbandgainLabel  matlab.ui.control.Label
        FrequencySpecificationsPanel  matlab.ui.container.Panel
        FsEditFieldLabel              matlab.ui.control.Label
        FsEditField                   matlab.ui.control.NumericEditField
        FcEditFieldLabel              matlab.ui.control.Label
        FcEditField                   matlab.ui.control.NumericEditField
        FreqUnitsDropDownLabel        matlab.ui.control.Label
        FreqUnitsDropDown             matlab.ui.control.DropDown
        leastPanel                    matlab.ui.container.Panel
        NooffreqpointskLabel          matlab.ui.control.Label
        NooffreqpointskSpinner        matlab.ui.control.Spinner
        InfoButtonGroup               matlab.ui.container.ButtonGroup
        LowpassFiniteImpulseResponseFilterDesigntoolLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        DesignMethodPanel             matlab.ui.container.Panel
        DesignMethodDropDown          matlab.ui.control.DropDown
        Image2                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        fc, fs, freqUnits,
        fpass, fstop,
        nTabs,
        filterType,
        windowType,
        hd,     % filter coefficients
        sF
    end
    
    methods (Access = private)
        
        function hd = windowMethod(app)
    
        % 1 - Computing frequency vector
        f = repelem(2*app.fc/app.fs,2);
        freq = [0;f(:);1];
        
        % 2 - Computing magnitudes
        mag = rem(1+(0:1), 2);
        magnitude = repelem(mag(:),2);
        
        % 3 - Setting window according to its type.
        switch (app.windowType)
            case "Blackman"
                wind = blackman(app.nTabs);
            case "Chebyshev"
                sidelobeAtten = app.parEditField.Value;
                wind = chebwin(app.nTabs, sidelobeAtten);
            case "Kaiser"
                beta = app.parEditField.Value;
                wind = kaiser(app.nTabs, beta);
            case "Rectangular"
                wind = rectwin(app.nTabs);
        end
        wind = wind';
        
        % 4 - Calculating the unwindowed impulse response
        h_ideal = firls(app.nTabs - 1, freq, magnitude);
        
        % 5 - Windowing the impulse response in order to get the filter
        hd = h_ideal .* wind; 
        
        end
        
        function refreshPlot(app)
        app.figure.YLabel.String = 'Magnitude';
        [h, f] = freqz(app.hd, 1);
        x_axis = f * app.fs / (2*pi*app.freqUnits);
        if (app.LogCheckBox.Value)
            y_axis = 20*log10(abs(h));
            app.figure.YLabel.String = 'Magnitude (db)';
 
        else
            y_axis = abs(h);
        end
        set(app.sF, 'XData', x_axis, 'YData', y_axis);
        axis(app.figure, 'tight');
        if (app.LogCheckBox.Value)
            app.figure.YLim = [min(y_axis, [], 'all'), 1.2*(max(y_axis, [], 'all') + 5)];
        end
        
        
        % Setting tilte of x-axis
        switch (app.freqUnits)
            case 1
                app.figure.XLabel.String = 'Frequency (Hz)';
            case 1000
                app.figure.XLabel.String = 'Frequency (kHz)';
            case 1000000
                app.figure.XLabel.String = 'Frequency (MHz)';
            otherwise
                app.figure.XLabel.String = 'Frequency (GHz)';
        end
        
        drawnow
        end
        
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.freqUnits = str2double(app.FreqUnitsDropDown.Value);
            app.fc = app.FcEditField.Value * app.freqUnits;
            app.fs = app.FsEditField.Value * app.freqUnits;
            app.nTabs = app.NooftapsSpinner.Value;
            app.windowType = app.WindowDropDown.Value;
            app.hd = windowMethod(app);
            [h, f] = freqz(app.hd, 1);
            % for first time, set to default values.
            app.sF = plot(app.figure, f*48/(2*pi), 20*log10(abs(h)), 'Color', [0.73 0.91 0.91]);
            axis(app.figure, "tight");
            app.figure.YLim = [-100 10];
            drawnow
        end

        % Value changed function: WindowDropDown
        function WindowDropDownValueChanged(app, event)
            app.windowType = app.WindowDropDown.Value;
            app.BetaEditFieldLabel.Visible = "on";
            app.parEditField.Visible = "on";
            switch app.windowType
                case "Chebyshev"
                    app.BetaEditFieldLabel.Text = "SL Atten. [db]";
                    app.parEditField.Value = 100;
                case "Kaiser"
                    app.BetaEditFieldLabel.Text = "Beta";
                    app.parEditField.Value = 0.5;
                otherwise    
                    app.BetaEditFieldLabel.Visible = "off";
                    app.parEditField.Visible = "off";
            end
        end

        % Button pushed function: DesignButton
        function DesignButtonPushed(app, event)
        design_method = app.DesignMethodDropDown.Value; 
        app.freqUnits = str2double(app.FreqUnitsDropDown.Value);
        app.fc = app.FcEditField.Value * app.freqUnits;
        app.fs = app.FsEditField.Value * app.freqUnits;
        app.nTabs = app.NooftapsSpinner.Value;
        switch (design_method)
            case "Windowed Sinc"
                app.windowType = app.WindowDropDown.Value;
                app.hd = windowMethod(app);
                
            case "Least Squares"
                %leastSquares
                k = app.NooffreqpointskSpinner.Value;
                wpass = app.WpassEditField.Value;
                wstop = app.WstopEditField.Value;
                [~, app.hd]= weightedleastSquares(app.nTabs, k, 2*app.fc/app.fs, wpass, wstop);
        end
        refreshPlot(app);
        end

        % Value changed function: DesignMethodDropDown
        function DesignMethodDropDownValueChanged(app, event)
            dm = app.DesignMethodDropDown.Value;
            
            switch (dm)
                case "Windowed Sinc"
                    app.noOptionsPanel.Visible = "off";
                    app.boOptionsPanel.Visible = "on";
                    app.leastPanel.Visible = "off";
                    app.NooftapsSpinner.Step = 1;
                    app.NooftapsSpinner.Limits = [2 inf];
                otherwise
                    app.noOptionsPanel.Visible = "on";
                    app.boOptionsPanel.Visible = "off";
                    app.leastPanel.Visible = "on";
                    app.NooftapsSpinner.Step = 2;
                    app.NooftapsSpinner.Limits = [3 inf];
            end
        end

        % Value changed function: LogCheckBox
        function LogCheckBoxValueChanged(app, event)
            [h, f] = freqz(app.hd, 1);
            if (app.LogCheckBox.Value)
                y_axis = 20*log10(abs(h));
                app.figure.YLabel.String = 'Magnitude (db)';
            else
                y_axis = abs(h);
                app.figure.YLabel.String = 'Magnitude';
            end
            set(app.sF, 'YData', y_axis)
            axis(app.figure, 'tight');
            if (app.LogCheckBox.Value)
                app.figure.YLim = [min(y_axis, [], 'all'), 1.2*(max(y_axis, [], 'all') + 5)];
            end
            drawnow
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
        export2wsdlg({'Exporting the filter coefficients to a variable named: '}, {'hd'}, {app.hd});
        end

        % Value changed function: FreqUnitsDropDown
        function FreqUnitsDropDownValueChanged(app, event)
            value = str2double(app.FreqUnitsDropDown.Value);
            app.FsEditField.Value = app.fs/value;
            app.FcEditField.Value = app.fc/value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FIRFilterDesignerUIFigure = uifigure('Visible', 'off');
            app.FIRFilterDesignerUIFigure.Name = 'FIR Filter Designer';
            app.MagnitudeResponsePanel = uipanel(app.FIRFilterDesignerUIFigure);
            app.MagnitudeResponsePanel.Title = 'Magnitude Response';
            app.figure = uiaxes(app.MagnitudeResponsePanel);
            title(app.figure, '')
            xlabel(app.figure, 'Frequency (kHz)')
            ylabel(app.figure, 'Magnitude (db)')
            app.LogCheckBox = uicheckbox(app.MagnitudeResponsePanel);
            app.LogCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogCheckBoxValueChanged, true);
            app.LogCheckBox.Text = 'Logarithmic Scale';
            app.LogCheckBox.Value = true;
            app.FilterLengthPanel = uipanel(app.FIRFilterDesignerUIFigure);
            app.FilterLengthPanel.Title = 'Filter Length';
            app.NooftapsSpinnerLabel = uilabel(app.FilterLengthPanel);
            app.NooftapsSpinnerLabel.Text = 'No. of taps';
            app.NooftapsSpinner = uispinner(app.FilterLengthPanel);
            app.NooftapsSpinner.Limits = [2 Inf];
            app.NooftapsSpinner.Value = 101;
            app.OptionsPanel = uipanel(app.FIRFilterDesignerUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.WindowDropDownLabel = uilabel(app.OptionsPanel);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.OptionsPanel);
            app.WindowDropDown.Items = {'Blackman', 'Chebyshev', 'Kaiser', 'Rectangular'};
            app.WindowDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowDropDownValueChanged, true);
            app.WindowDropDown.Value = 'Kaiser';
            app.BetaEditFieldLabel = uilabel(app.OptionsPanel);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.parEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.parEditField.ValueDisplayFormat = '%11.4f';
            app.parEditField.Value = 0.5;
            app.noOptionsPanel = uipanel(app.OptionsPanel);
            app.noOptionsPanel.Visible = 'off';
            app.TherearenooptionsforthisdesignmethodLabel = uilabel(app.noOptionsPanel);
            app.TherearenooptionsforthisdesignmethodLabel.Text = {'There are no options'; 'for this design '; 'method.'};
            app.DesignButton = uibutton(app.FIRFilterDesignerUIFigure, 'push');
            app.DesignButton.ButtonPushedFcn = createCallbackFcn(app, @DesignButtonPushed, true);
            app.DesignButton.Text = 'Design';
            app.MagnitudeSpecificationsPanel = uipanel(app.FIRFilterDesignerUIFigure);
            app.MagnitudeSpecificationsPanel.Title = 'Magnitude Specifications';
            app.EnteraweightvalueforeachbanbelowLabel = uilabel(app.MagnitudeSpecificationsPanel);
            app.EnteraweightvalueforeachbanbelowLabel.Text = {'Enter a weight value '; 'for each ban below.'};
            app.WpassEditFieldLabel = uilabel(app.MagnitudeSpecificationsPanel);
            app.WpassEditFieldLabel.Text = 'Wpass';
            app.WpassEditField = uieditfield(app.MagnitudeSpecificationsPanel, 'numeric');
            app.WpassEditField.ValueDisplayFormat = '%11.3f';
            app.WpassEditField.Value = 1;
            app.WstopEditFieldLabel = uilabel(app.MagnitudeSpecificationsPanel);
            app.WstopEditFieldLabel.Text = {'Wstop'; ''};
            app.WstopEditField = uieditfield(app.MagnitudeSpecificationsPanel, 'numeric');
            app.WstopEditField.ValueDisplayFormat = '%11.3f';
            app.WstopEditField.Value = 1;
            app.boOptionsPanel = uipanel(app.MagnitudeSpecificationsPanel);
            app.AttenuationisfixedathalfthepassbandgainLabel = uilabel(app.boOptionsPanel);
            app.AttenuationisfixedathalfthepassbandgainLabel.Text = {'Attenuation is fixed at'; 'half the passband '; 'gain.'};
            app.FrequencySpecificationsPanel = uipanel(app.FIRFilterDesignerUIFigure);
            app.FrequencySpecificationsPanel.Title = 'Frequency Specifications';
            app.FsEditFieldLabel = uilabel(app.FrequencySpecificationsPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.FrequencySpecificationsPanel, 'numeric');
            app.FsEditField.ValueDisplayFormat = '%11.3f';
            app.FsEditField.Value = 48;
            app.FcEditFieldLabel = uilabel(app.FrequencySpecificationsPanel);
            app.FcEditFieldLabel.Text = 'Fc';
            app.FcEditField = uieditfield(app.FrequencySpecificationsPanel, 'numeric');
            app.FcEditField.ValueDisplayFormat = '%11.3f';
            app.FcEditField.Value = 10.8;
            app.FreqUnitsDropDownLabel = uilabel(app.FrequencySpecificationsPanel);
            app.FreqUnitsDropDownLabel.Text = 'Freq. Units';
            app.FreqUnitsDropDown = uidropdown(app.FrequencySpecificationsPanel);
            app.FreqUnitsDropDown.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.FreqUnitsDropDown.ItemsData = {'1', '1000', '1000000', '1000000000'};
            app.FreqUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @FreqUnitsDropDownValueChanged, true);
            app.FreqUnitsDropDown.Value = '1000';
            app.leastPanel = uipanel(app.FrequencySpecificationsPanel);
            app.leastPanel.Visible = 'off';
            app.NooffreqpointskLabel = uilabel(app.leastPanel);
            app.NooffreqpointskLabel.Text = {'No. of  freq. '; 'points k'};
            app.NooffreqpointskSpinner = uispinner(app.leastPanel);
            app.NooffreqpointskSpinner.Step = 2;
            app.NooffreqpointskSpinner.Limits = [3 Inf];
            app.NooffreqpointskSpinner.Value = 101;
            app.InfoButtonGroup = uibuttongroup(app.FIRFilterDesignerUIFigure);
            app.InfoButtonGroup.Title = 'Info';
            app.LowpassFiniteImpulseResponseFilterDesigntoolLabel = uilabel(app.InfoButtonGroup);
            app.LowpassFiniteImpulseResponseFilterDesigntoolLabel.Text = {'Lowpass Finite '; 'Impulse Response '; 'Filter Design tool.'};
            app.Image = uiimage(app.FIRFilterDesignerUIFigure);
            app.Image.ImageSource = 'dsp-icon.png';
            app.DesignMethodPanel = uipanel(app.FIRFilterDesignerUIFigure);
            app.DesignMethodPanel.Title = 'Design Method';
            app.DesignMethodDropDown = uidropdown(app.DesignMethodPanel);
            app.DesignMethodDropDown.Items = {'Windowed Sinc', 'Least Squares'};
            app.DesignMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DesignMethodDropDownValueChanged, true);
            app.DesignMethodDropDown.Value = 'Windowed Sinc';
            app.Image2 = uiimage(app.FIRFilterDesignerUIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.Tooltip = {'save filter'};
            app.Image2.ImageSource = 'icons8-save-100.png';
            % Show the figure after all components are created
            app.FIRFilterDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FIR_FilterDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FIRFilterDesignerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FIRFilterDesignerUIFigure)
        end
    end
end